classdef processSVSwithBlockAveraging < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        UIAxes                 matlab.ui.control.UIAxes
        MaxYEditFieldLabel     matlab.ui.control.Label
        MaxYEditField          matlab.ui.control.NumericEditField
        MinYEditFieldLabel     matlab.ui.control.Label
        MinYEditField          matlab.ui.control.NumericEditField
        MaxXEditFieldLabel     matlab.ui.control.Label
        MaxXEditField          matlab.ui.control.NumericEditField
        MinXEditFieldLabel     matlab.ui.control.Label
        MinXEditField          matlab.ui.control.NumericEditField
        ButtonGroup            matlab.ui.container.ButtonGroup
        RealButton             matlab.ui.control.RadioButton
        ImagButton             matlab.ui.control.RadioButton
        AbsButton              matlab.ui.control.RadioButton
        NWSdatButton           matlab.ui.control.Button
        WSdatButton            matlab.ui.control.Button
        NWSdatEditField        matlab.ui.control.EditField
        WSdatEditField         matlab.ui.control.EditField
        CalculateButton        matlab.ui.control.Button
        CurrentListBoxLabel    matlab.ui.control.Label
        CurrentListBox         matlab.ui.control.ListBox
        AvailableListBoxLabel  matlab.ui.control.Label
        AvailableListBox       matlab.ui.control.ListBox
        OptionsButton          matlab.ui.control.Button
        UButton                matlab.ui.control.Button
        DButton                matlab.ui.control.Button
        RButton                matlab.ui.control.Button
        LButton                matlab.ui.control.Button
        ReadButton             matlab.ui.control.Button
        SaveProtButton         matlab.ui.control.Button
        LoadProtButton         matlab.ui.control.Button
    end

    
    properties (Access = public)
        nws_dat % Non water suppressed dat file
        ws_dat % water suppressed dat file
        opts % reconstruction options
        data % data and parameters
    end
    
    properties (Access = private)
        plotopts % display options for plot
    end

    methods (Access = private)
    
        function updatePlot(app)
            
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, prot_mat, nws_dat, ws_dat)
            
            addpath('mapVBVD'); % for reading dat files
            
            app.UButton.Text = char(9650);
            app.DButton.Text = char(9660);
            app.LButton.Text = char(9668);
            app.RButton.Text = char(9658);
            if nargin>1
                load(prot_mat,'opts')
                app.opts = opts;
            else
                app.opts = [];
            end
            if nargin>2
                app.NWSdatEditField.Value = nws_dat;
                app.WSdatEditField.Value = ws_dat;
                app.nws_dat = nws_dat;
                app.ws_dat = ws_dat;
            else
                app.nws_dat = [];
                app.ws_dat = [];
            end
        end

        % Button pushed function: UButton
        function UButtonPushed(app, event)
            curlist = app.CurrentListBox.Items;
            curval = app.CurrentListBox.Value;
            curind = find(strcmp(curlist,curval));
            if curind>1
                listlength = length(curlist);
                if curind==listlength
                    newinds = [1:curind-2 curind curind-1];
                else
                    newinds = [1:curind-2 curind curind-1 curind+1:listlength];
                end
                newlist = curlist(newinds);
                app.CurrentListBox.Items = newlist;
            end
        end

        % Button pushed function: DButton
        function DButtonPushed(app, event)
            curlist = app.CurrentListBox.Items;
            curval = app.CurrentListBox.Value;
            curind = find(strcmp(curlist,curval));
            listlength = length(curlist);
            if curind<listlength
                if curind==1
                    newinds = [curind+1 curind curind+2:listlength];
                else
                    newinds = [1:curind-1 curind+1 curind curind+2:listlength];
                end
                newlist = curlist(newinds);
                app.CurrentListBox.Items = newlist;
            end
        end

        % Button pushed function: LButton
        function LButtonPushed(app, event)
            curlist = app.CurrentListBox.Items;
            curval = app.CurrentListBox.Value;
            curind = find(strcmp(curlist,curval));
            avlist = app.AvailableListBox.Items;
            avval = app.AvailableListBox.Value;
            avind = find(strcmp(avlist,avval));
            avlength = length(avlist);
            newlistcur = [curlist(1:curind) avval curlist(curind+1:end)];
            if avind==1
                newlistav = avlist(2:end);
            elseif avind==avlength
                newlistav = avlist(1:avind-1);
            else
                newlistav = [avlist(1:avind-1) avlist(avind+1:end)];
            end
            app.CurrentListBox.Items = newlistcur;
            app.AvailableListBox.Items = newlistav;
        end

        % Button pushed function: RButton
        function RButtonPushed(app, event)
            curlist = app.CurrentListBox.Items;
            curval = app.CurrentListBox.Value;
            curind = find(strcmp(curlist,curval));
            avlist = app.AvailableListBox.Items;
            avval = app.AvailableListBox.Value;
            avind = find(strcmp(avlist,avval));
            curlength = length(curlist);
            if curind==1
                newlistcur = curlist(2:end);
            elseif curind==curlength
                newlistcur = curlist(1:curind-1);
            else
                newlistcur = [curlist(1:curind-1) curlist(curind+1:end)];
            end
            newlistav = [avlist(1:avind) curval avlist(avind+1:end)];
            app.CurrentListBox.Items = newlistcur;
            app.AvailableListBox.Items = newlistav;
        end

        % Button pushed function: NWSdatButton
        function NWSdatButtonPushed(app, event)
            [fname,fpath] = uigetfile;
            dat = fullfile(fpath,fname);
            app.nws_dat = dat;
            app.NWSdatEditField.Value = dat;
        end

        % Button pushed function: WSdatButton
        function WSdatButtonPushed(app, event)
            [fname,fpath] = uigetfile;
            dat = fullfile(fpath,fname);
            app.ws_dat = dat;
            app.WSdatEditField.Value = dat;
        end

        % Value changed function: NWSdatEditField
        function NWSdatEditFieldValueChanged(app, event)
            value = app.NWSdatEditField.Value;
            app.nws_dat = value;
        end

        % Value changed function: WSdatEditField
        function WSdatEditFieldValueChanged(app, event)
            value = app.WSdatEditField.Value;
            app.ws_dat = value;
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            curlist = app.CurrentListBox.Items;
            app.data.isfid = true;
            ws = app.data.ws_orig;
            nws = app.data.nws_orig;
            for ii=1:length(curlist)
                curval = curlist{ii};
                switch curval
                    case 'Eddy current Corr'
                        [ws,nws] = eddyCurrentCorrection(nws,ws,app.opts.eccopts);
                    case 'Coil Combination'
                        [app.data.coilweights,ws,nws] = coilCombinationNoPC(nws,app.opts.ccopts.minsig_frac,ws);
                    case 'Apodization'
                        [ws,app.data.filt] = expFilter(app.data.t,app.opts.filtopts.lb,ws);
                    case 'FT'
                        ws = fftshift(fft(ws,[],1),1);
                        nws = fftshift(fft(nws,[],1),1);
                        app.data.isfid = false;
                    case 'Average'
                        ws = mean(ws,2);
                    case 'Block'
                        ws = blockAverage(ws,app.opts.baopts.block_size);
                    case 'Auto Baseline Corr'
                        ws = baselineCorrect(ws,app.data.ppm,app.opts.baseopts);
                    case 'Man Baseline Corr'
                        
                    case 'Man Phasing'
                        
                    case 'Water Supp'
                        ws = removeResidualWater(ws,app.opts.wsopts);
                    case 'Wat Freq Sh'
                        [nws,app.data.bulkHzShift] = bulkSpectrumShift(nws,app.data.hz);
                        ws = shiftSpectrumFrequency(ws,app.data.bulkHzShift,app.data.t);
                    case 'Met Freq/Ph'
                        [ws,app.data.fineHzShift] = peakSpectrumShift(ws,app.data.hz,app.data.f0,app.data.metopt.lb,app.data.metopt.peaks,app.data.metopt.ranges);
                        ws = peakSpectrumPhase(ws,app.data.ppm,app.data.t,app.data.metopt.lb,app.data.metopt.peaks,app.data.metopt.ranges/2);
                    otherwise
                        warndlg('Unexpected option')                        
                end
                app.data.ws = ws;
                app.data.nws = nws;
                updatePlot(app);
            end
        end

        % Button pushed function: ReadButton
        function ReadButtonPushed(app, event)
            % non water suppressed
            nws_obj = mapVBVD(app.nws_dat);
            nws_obj.image.flagDoAverage = true;
            app.data.bw = 1e9/nws_obj.hdr.Config.DwellTimeSig;
            app.data.f0 = 1e-6*nws_obj.hdr.Config.Frequency;
            app.data.nch = nws_obj.image.NCha;
            app.data.npts = nws_obj.image.NCol;
            
            app.data.nws = nws_obj.image{''};
            app.data.nws_orig = nws_obj.image{''}; % original will stay unchanged
            
            % water suppressed
            ws_obj = mapVBVD(app.ws_dat);
            if ~isequal(app.data.bw,1e9/ws_obj.hdr.Config.DwellTimeSig) || ~isequal(f0,1e-6*ws_obj.hdr.Config.Frequency) || ~isequal(nch,ws_obj.image.NCha) || ~isequal(npts,ws_obj.image.NCol)
                errordlg('inconsistent protocols')
            end
            app.data.nave = ws_obj.image.NAve;
            
            app.data.ws = ws_obj.image{''};
            app.data.ws_orig = ws_obj.image{''}; % original will stay unchanged
            
            % time axis
            app.data.t = (0:npts-1)*1/bw;
            
            % freq axes
            app.data.hz = (-1/2:1/npts:1/2-1/npts)*bw;
            app.data.ppm = 4.72 + hz/f0;

            app.data.isfid = true; % data is originally in time domain
            
            % default options for each function
            if ~isfield(app.opts,'eccopts'), app.opts.eccopts = 0; end % Klose's method for eddy current compensation
            if ~isfield(app.opts,'filtopts') || ~isfield(app.opts.filtopts,'lb'), app.opts.filtopts.lb = 7; end % line broadening for filter
            if ~isfield(app.opts,'ccopts') || ~isfield(app.opts.ccopts,'minsig_frac'), app.opts.ccopts.minsig_frac = 0.2; end % minimum relative coil signal to include
            if ~isfield(app.opts,'baopts') || ~isfield(app.opts.baopts,'block_size'), app.opts.baopts.block_size = min(32,app.data.nave/4); end % block average size
            if ~isfield(app.opts,'wsopts') || ~isfield(app.opts.wsopts,'type'), app.opts.wsopts.type = 'hsvd'; end
            if ~isfield(app.opts,'wsopts') || ~isfield(app.opts.wsopts,'hsvd') || ~isfield(app.opts.wsopts.hsvd,'bounds'), app.opts.wsopts.hsvd.bounds = [-0.025 0.025]; end% normalized bounds for water
            if ~isfield(app.opts,'wsopts') || ~isfield(app.opts.wsopts,'hsvd') || ~isfield(app.opts.wsopts.hsvd,'nsin'), app.opts.wsopts.hsvd.nsin = 25; end % number of decaying sinusoids
            if ~isfield(app.opts,'wsopts') || ~isfield(app.opts.wsopts,'wavelet') || ~isfield(app.opts.wsopts.wavelet,'zf'), app.opts.wsopts.wavelet.zf = 0; end % zero filling
            if ~isfield(app.opts,'wsopts') || ~isfield(app.opts.wsopts,'wavelet') || ~isfield(app.opts.wsopts.wavelet,'scale'), app.opts.wsopts.wavelet.scale = 7; end
            if ~isfield(app.opts,'wsopts') || ~isfield(app.opts.wsopts,'wavelet') || ~isfield(app.opts.wsopts.wavelet,'type'), app.opts.wsopts.wavelet.type = 'Daubechies'; end
            if ~isfield(app.opts,'wsopts') || ~isfield(app.opts.wsopts,'wavelet') || ~isfield(app.opts.wsopts.wavelet,'par'), app.opts.wsopts.wavelet.par = 10; end
            if ~isfield(app.opts,'metopts') || ~isfield(app.opts.metopts,'peaks'), app.opts.metopts.peaks = [3.03 3.21 3.91 2.02]; end
            if ~isfield(app.opts,'metopts') || ~isfield(app.opts.metopts,'ranges'), app.opts.metopts.ranges = 0.1*ones(lengths(app.opts.metopts.peaks),2); end
            if ~isfield(app.opts,'metopts') || ~isfield(app.opts.metopts,'addlb'), app.opts.metopts.addlb = 4; end % additional line broadening to locate peaks
            if ~isfield(app.opts,'baseopts') || ~isfield(app.opts.baseopts,'method'), app.opts.baseopts.method = 'pchip'; end
            if ~isfield(app.opts,'baseopts') || ~isfield(app.opts.baseopts,'stepsize'), app.opts.baseopts.stepsize = 0.5; end
            if ~isfield(app.opts,'baseopts') || ~isfield(app.opts.baseopts,'windowsize'), app.opts.baseopts.windowsize = 0.5; end
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            updatePlot(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'ppm')
            app.MaxYEditFieldLabel = uilabel(app.UIFigure);
            app.MaxYEditFieldLabel.Text = 'Max Y';
            app.MaxYEditField = uieditfield(app.UIFigure, 'numeric');
            app.MinYEditFieldLabel = uilabel(app.UIFigure);
            app.MinYEditFieldLabel.Text = 'Min Y';
            app.MinYEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxXEditFieldLabel = uilabel(app.UIFigure);
            app.MaxXEditFieldLabel.Text = 'Max X';
            app.MaxXEditField = uieditfield(app.UIFigure, 'numeric');
            app.MinXEditFieldLabel = uilabel(app.UIFigure);
            app.MinXEditFieldLabel.Text = 'Min X';
            app.MinXEditField = uieditfield(app.UIFigure, 'numeric');
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.RealButton = uiradiobutton(app.ButtonGroup);
            app.RealButton.Text = 'Real';
            app.RealButton.Value = true;
            app.ImagButton = uiradiobutton(app.ButtonGroup);
            app.ImagButton.Text = 'Imag';
            app.AbsButton = uiradiobutton(app.ButtonGroup);
            app.AbsButton.Text = 'Abs';
            app.NWSdatButton = uibutton(app.UIFigure, 'push');
            app.NWSdatButton.ButtonPushedFcn = createCallbackFcn(app, @NWSdatButtonPushed, true);
            app.NWSdatButton.Text = 'NWS dat';
            app.WSdatButton = uibutton(app.UIFigure, 'push');
            app.WSdatButton.ButtonPushedFcn = createCallbackFcn(app, @WSdatButtonPushed, true);
            app.WSdatButton.Text = 'WS dat';
            app.NWSdatEditField = uieditfield(app.UIFigure, 'text');
            app.NWSdatEditField.ValueChangedFcn = createCallbackFcn(app, @NWSdatEditFieldValueChanged, true);
            app.WSdatEditField = uieditfield(app.UIFigure, 'text');
            app.WSdatEditField.ValueChangedFcn = createCallbackFcn(app, @WSdatEditFieldValueChanged, true);
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.CurrentListBoxLabel = uilabel(app.UIFigure);
            app.CurrentListBoxLabel.Text = 'Current';
            app.CurrentListBox = uilistbox(app.UIFigure);
            app.CurrentListBox.Items = {'Eddy Current Corr', 'Coil Combination', 'Apodization', 'FT', 'Average', 'Auto Baseline Corr'};
            app.CurrentListBox.Value = 'Eddy Current Corr';
            app.AvailableListBoxLabel = uilabel(app.UIFigure);
            app.AvailableListBoxLabel.Text = 'Available';
            app.AvailableListBox = uilistbox(app.UIFigure);
            app.AvailableListBox.Items = {'Block', 'Man Phasing', 'Man Baseline Corr', 'Water Supp', 'Wat Freq Sh', 'Met Freq/Ph'};
            app.AvailableListBox.Multiselect = 'on';
            app.AvailableListBox.Value = {'Block'};
            app.OptionsButton = uibutton(app.UIFigure, 'push');
            app.OptionsButton.Text = 'Options';
            app.UButton = uibutton(app.UIFigure, 'push');
            app.UButton.ButtonPushedFcn = createCallbackFcn(app, @UButtonPushed, true);
            app.UButton.Text = 'U';
            app.DButton = uibutton(app.UIFigure, 'push');
            app.DButton.ButtonPushedFcn = createCallbackFcn(app, @DButtonPushed, true);
            app.DButton.Text = 'D';
            app.RButton = uibutton(app.UIFigure, 'push');
            app.RButton.ButtonPushedFcn = createCallbackFcn(app, @RButtonPushed, true);
            app.RButton.Text = 'R';
            app.LButton = uibutton(app.UIFigure, 'push');
            app.LButton.ButtonPushedFcn = createCallbackFcn(app, @LButtonPushed, true);
            app.LButton.Text = 'L';
            app.ReadButton = uibutton(app.UIFigure, 'push');
            app.ReadButton.ButtonPushedFcn = createCallbackFcn(app, @ReadButtonPushed, true);
            app.ReadButton.Text = 'Read';
            app.SaveProtButton = uibutton(app.UIFigure, 'push');
            app.SaveProtButton.Text = 'Save Prot';
            app.LoadProtButton = uibutton(app.UIFigure, 'push');
            app.LoadProtButton.Text = 'Load Prot';
        end
    end

    methods (Access = public)

        % Construct app
        function app = processSVSwithBlockAveraging(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end